classdef f0_ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        endEditField             matlab.ui.control.NumericEditField
        endEditFieldLabel        matlab.ui.control.Label
        startEditField           matlab.ui.control.NumericEditField
        startEditFieldLabel      matlab.ui.control.Label
        StopButton               matlab.ui.control.Button
        ScaleDropDown            matlab.ui.control.DropDown
        ScaleDropDownLabel       matlab.ui.control.Label
        ReadButton               matlab.ui.control.Button
        F_maxEditField           matlab.ui.control.NumericEditField
        F_maxEditFieldLabel      matlab.ui.control.Label
        F_minEditField           matlab.ui.control.NumericEditField
        F_minEditFieldLabel      matlab.ui.control.Label
        T_maxEditField           matlab.ui.control.NumericEditField
        T_maxEditFieldLabel      matlab.ui.control.Label
        T_minEditField           matlab.ui.control.NumericEditField
        T_minEditFieldLabel      matlab.ui.control.Label
        FrequencyEditField       matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel  matlab.ui.control.Label
        TimeEditField            matlab.ui.control.NumericEditField
        TimeEditFieldLabel       matlab.ui.control.Label
        woriginalButton          matlab.ui.control.StateButton
        PartDropDown             matlab.ui.control.DropDown
        PartDropDownLabel        matlab.ui.control.Label
        PlayButton               matlab.ui.control.Button
        DeleteButton             matlab.ui.control.Button
        SwitchDropDown           matlab.ui.control.DropDown
        SwitchDropDownLabel      matlab.ui.control.Label
        NewButton                matlab.ui.control.Button
        WriteButton              matlab.ui.control.Button
        ModeButtonGroup          matlab.ui.container.ButtonGroup
        LineButton               matlab.ui.control.RadioButton
        AutoButton               matlab.ui.control.RadioButton
        EraseButton              matlab.ui.control.RadioButton
        DrawButton               matlab.ui.control.RadioButton
        UIAxes_H                 matlab.ui.control.UIAxes
        UIAxes_P                 matlab.ui.control.UIAxes
    end

    properties (Access = private)
        mouseclick = false; % Description
        mode = 'd';
        drawcolor = 'blue';
        mkrtype = 'o';
        
        song
        T
        F
        fs
        dec_n
        P
        H
        x
        
        idx_v = 1;
        idx_d = 1;
        f0mat
        f0buf
        guide = zeros(2, 0);
        autoflag = false;
        lineflag = false;
        
        outputdir = './output/';
    end
    
    methods (Access = private)
        function h_drawing(app)
            % Get coordinates
            xy_P = app.UIAxes_P.CurrentPoint(1, 1:2);
            xy_H = app.UIAxes_H.CurrentPoint(1, 1:2);
            
            if xy_P(2) < app.F(1)
                xy = [xy_P(1) xy_H(2)];
            else
                xy = xy_P;
            end
            
            % Print out to the text edit fields
            set(app.TimeEditField, 'Value', xy(1));
            set(app.FrequencyEditField, 'Value', xy(2));
            
            % Draw and store
            if app.mouseclick && (app.mode == 'd' || app.mode == 'e')
                scatter(app.UIAxes_P, xy(1), xy(2), 2, app.drawcolor, app.mkrtype);
                scatter(app.UIAxes_H, xy(1), xy(2), 2, app.drawcolor, app.mkrtype);
                
                [~, idx] = min(abs(app.T - xy(1)));
                app.f0buf(idx) = xy(2);
            end
        end
        
        function h_refresh(app)
            % Restore to initial surf plot
            delete(app.UIAxes_P.Children(1:end - 1));
            delete(app.UIAxes_H.Children(1:end - 1));
            hold(app.UIAxes_P, 'on');
            hold(app.UIAxes_H, 'on');
            
            % Draw lines on the surf plot
            for i=1:size(app.f0mat, 2)
                if i == app.idx_v
                    color = [0.0 0.0 1.0];
                else
                    color = [0.4 0.2 0.7];
                end
                
                scatter(app.UIAxes_P, app.T, app.f0mat(:, i), 2, color);
                scatter(app.UIAxes_H, app.T, app.f0mat(:, i), 2, color);
            end
            
            hold(app.UIAxes_P, 'off');
            hold(app.UIAxes_H, 'off');
        end
        
        function h_startup(app)
            % Read audio file
            [file, path] = uigetfile('*.*');

            [P, H, t_P, app.x, freqset, app.dec_n, app.fs] = f0_engine(strcat(path, file));
            
            app.T = t_P;
            app.F = freqset;
            app.P = 10*log10(P.*2);
            app.H = H - max(H(:)) - 1;

            app.outputdir = config_f0().outputdir;
            
            % Get song name
            s = strsplit(file, '.');
            app.song = s{1};
            
            % Figure setup
            %surf(app.UIAxes_P, app.T, app.F, app.P, 'edgecolor', 'none', 'ButtonDownFcn', @P_ButtonDownFcn);
            %view(app.UIAxes_P, 0, 90);
            imagesc(app.UIAxes_P, app.T, app.F, app.P, 'ButtonDownFcn', @P_ButtonDownFcn);
            set(app.UIAxes_P, 'YDir', 'Normal');

            function P_ButtonDownFcn(~, event)
                hold(app.UIAxes_P, 'on');
                hold(app.UIAxes_H, 'on');
                app.mouseclick = true;
                
                if app.mode == 'a' && event.Button == 1
                    xy = app.UIAxes_P.CurrentPoint(1, 1:2);
                    scatter(app.UIAxes_P, xy(1), xy(2), 2, app.drawcolor, app.mkrtype);
                    scatter(app.UIAxes_H, xy(1), xy(2), 2, app.drawcolor, app.mkrtype);
                    
                    [~, idx_t] = min(abs(app.T - xy(1)));
                    [~, idx_f] = min(abs(app.F - xy(2)));
                    app.guide(:, end + 1) = [idx_t; idx_f];
                elseif app.mode == 'a' && event.Button == 3
                    if size(app.guide, 2) >= 4
                        guide_p = app.guide(:, 1:end - 2);
                        [~, idx] = sort(guide_p(1, :), 'asc');
                        guide_p = guide_p(:, idx);
                        
                        guide_f = app.guide(2, end - 1:end);
                        f_ub = max(guide_f);
                        f_lb = min(guide_f);
                        
                        fig_w = uifigure;
                        w_d = uiprogressdlg(fig_w, 'Title','Search salience frequency path',...
                            'Indeterminate','on');
                        
                        for i=2:size(guide_p, 2)
                            x_start = guide_p(1, i - 1);
                            y_start = guide_p(2, i - 1);
                            x_end = guide_p(1, i);
                            y_end = guide_p(2, i);
                            
                            V = viterbidec(app.P(f_lb:f_ub, x_start:x_end), y_start - f_lb + 1, y_end - f_lb + 1, 6);
                            [idx, ~] = ind2sub(size(V), find(V ~= 0));
                            idx = idx + f_lb - 1;
                            app.f0buf(x_start:x_end) = app.F(idx);
                        end
                        
                        close(w_d);
                        close(fig_w);
                    end
                    
                    app.guide = zeros(2, 0);
                    app.autoflag = true;
                elseif app.mode == 'l' && event.Button == 1
                    xy = app.UIAxes_P.CurrentPoint(1, 1:2);
                    scatter(app.UIAxes_P, xy(1), xy(2), 2, app.drawcolor, app.mkrtype);
                    scatter(app.UIAxes_H, xy(1), xy(2), 2, app.drawcolor, app.mkrtype);
                    
                    [~, idx_t] = min(abs(app.T - xy(1)));
                    [~, idx_f] = min(abs(app.F - xy(2)));
                    
                    if size(app.guide, 2) == 0
                        app.guide(:, end + 1) = [idx_t; idx_f];
                    elseif size(app.guide, 2) == 1
                        app.guide(:, end + 1) = [idx_t; idx_f];
                        
                        x_start = min(app.guide(1, :));
                        x_end = max(app.guide(1, :));
                        
                        app.f0buf(x_start:x_end) = app.F(app.guide(2, 1));
                        
                        app.guide = zeros(2, 0);
                        app.lineflag = true;
                    end
                end
            end
        
            %
            %surf(app.UIAxes_H, app.T, app.F, app.H, 'edgecolor', 'none', 'ButtonDownFcn', @H_ButtonDownFcn);
            %view(app.UIAxes_H, 0, 90);
            imagesc(app.UIAxes_H, app.T, app.F, app.H, 'ButtonDownFcn', @H_ButtonDownFcn);
            set(app.UIAxes_H, 'YDir', 'Normal');
            
            function H_ButtonDownFcn(~, event)
                hold(app.UIAxes_P, 'on');
                hold(app.UIAxes_H, 'on');
                app.mouseclick = true;
                
                if app.mode == 'a' && event.Button == 1
                    xy = app.UIAxes_H.CurrentPoint(1, 1:2);
                    scatter(app.UIAxes_P, xy(1), xy(2), 2, app.drawcolor, app.mkrtype);
                    scatter(app.UIAxes_H, xy(1), xy(2), 2, app.drawcolor, app.mkrtype);
                    
                    [~, idx_t] = min(abs(app.T - xy(1)));
                    [~, idx_f] = min(abs(app.F - xy(2)));
                    app.guide(:, end + 1) = [idx_t; idx_f];
                elseif app.mode == 'a' && event.Button == 3
                    if size(app.guide, 2) >= 4
                        guide_p = app.guide(:, 1:end - 2);
                        [~, idx] = sort(guide_p(1, :), 'asc');
                        guide_p = guide_p(:, idx);
                        
                        guide_f = app.guide(2, end - 1:end);
                        f_ub = max(guide_f);
                        f_lb = min(guide_f);
                        
                        fig_w = uifigure;
                        w_d = uiprogressdlg(fig_w, 'Title','Search salience frequency path',...
                            'Indeterminate','on');
                        
                        for i=2:size(guide_p, 2)
                            x_start = guide_p(1, i - 1);
                            y_start = guide_p(2, i - 1);
                            x_end = guide_p(1, i);
                            y_end = guide_p(2, i);
                            
                            V = viterbidec(app.H(f_lb:f_ub, x_start:x_end), y_start - f_lb + 1, y_end - f_lb + 1, 6);
                            [idx, ~] = ind2sub(size(V), find(V ~= 0));
                            idx = idx + f_lb - 1;
                            app.f0buf(x_start:x_end) = app.F(idx);
                        end
                        
                        close(w_d);
                        close(fig_w);
                    end
                    
                    app.guide = zeros(2, 0);
                    app.autoflag = true;
                elseif app.mode == 'l' && event.Button == 1
                    xy = app.UIAxes_H.CurrentPoint(1, 1:2);
                    scatter(app.UIAxes_P, xy(1), xy(2), 2, app.drawcolor, app.mkrtype);
                    scatter(app.UIAxes_H, xy(1), xy(2), 2, app.drawcolor, app.mkrtype);
                    
                    [~, idx_t] = min(abs(app.T - xy(1)));
                    [~, idx_f] = min(abs(app.F - xy(2)));
                    
                    if size(app.guide, 2) == 0
                        app.guide(:, end + 1) = [idx_t; idx_f];
                    elseif size(app.guide, 2) == 1
                        app.guide(:, end + 1) = [idx_t; idx_f];
                        
                        x_start = min(app.guide(1, :));
                        x_end = max(app.guide(1, :));
                        
                        app.f0buf(x_start:x_end) = app.guide(2, 1);
                        
                        app.guide = zeros(2, 0);
                        app.lineflag = true;
                    end
                end
            end
        
            %
            app.startEditField.Value = t_P(1);
            app.endEditField.Value = t_P(1) + (numel(app.x) - 1)/app.fs;

            xlim(app.UIAxes_P, [app.T(1) app.T(end)]);
            ylim(app.UIAxes_P, [app.F(1) app.F(end)]);
            app.UIAxes_P.YScale = 'log';
            
            xlim(app.UIAxes_H, [app.T(1) app.T(end)]);
            ylim(app.UIAxes_H, [app.F(1) app.F(end)]);
            app.UIAxes_H.YScale = 'log';
            
            set(app.F_maxEditField, 'Value', app.F(end));
            set(app.F_minEditField, 'Value', app.F(1));
            set(app.T_maxEditField, 'Value', app.T(end));
            set(app.T_minEditField, 'Value', app.T(1));
            
            %
            app.f0mat = zeros(length(app.T), 1);
            app.f0buf = zeros(length(app.T), 1);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('./lib/viterbi/');
            
            % UI setup
            iptPointerManager(app.UIFigure, 'enable');
            
            pm.enterFcn = [];
            pm.exitFcn  = [];
            pm.traverseFcn = @(~, ~) h_drawing(app);
            iptSetPointerBehavior(app.UIAxes_P, pm)
            iptSetPointerBehavior(app.UIAxes_H, pm)
            
            set(app.UIFigure, 'WindowButtonMotionFcn', @(~, ~) NaN)
            
            %%
            h_startup(app);
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            hold(app.UIAxes_P, 'off');
            hold(app.UIAxes_H, 'off');
            app.mouseclick = false;
            
            idx = find(app.f0buf ~= 0);
            
            if app.mode == 'd' && length(idx) > 1
                vq = interp1(app.T(idx), app.f0buf(idx), app.T(idx(1):idx(end)), 'spline');
                app.f0mat(idx(1):idx(end), app.idx_v) = vq;
                h_refresh(app);
            elseif app.mode == 'e' && ~isempty(idx)
                app.f0mat(idx(1):idx(end), app.idx_v) = 0;
                app.guide = zeros(2, 0);
                h_refresh(app);
            elseif app.autoflag
                app.autoflag = false;
                app.f0mat(idx(1):idx(end), app.idx_v) = app.f0buf(idx);
                h_refresh(app);
            elseif app.lineflag
                app.lineflag = false;
                app.f0mat(idx(1):idx(end), app.idx_v) = app.f0buf(idx);
                h_refresh(app);
            end
            
            app.f0buf(:) = 0;
        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModeButtonGroup.SelectedObject;
            
            if app.AutoButton == selectedButton
                app.mode = 'a';
                app.drawcolor = 'magenta';
                app.mkrtype = '^';
                app.guide = zeros(2, 0);
            elseif app.EraseButton == selectedButton
                app.mode = 'e';
                app.drawcolor = 'red';
                app.mkrtype = 'x';
            elseif app.DrawButton == selectedButton
                app.mode = 'd';
                app.drawcolor = 'blue';
                app.mkrtype = 'o';
            elseif app.LineButton == selectedButton
                app.mode = 'l';
                app.drawcolor = 'magenta';
                app.mkrtype = '^';
                app.guide = zeros(2, 0);
            end
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            app.f0mat(:, end + 1) = 0;
            
            app.SwitchDropDown.Items = [app.SwitchDropDown.Items, ...
                sprintf('Voice %d', size(app.f0mat, 2))];
            
            app.PartDropDown.Items = [app.PartDropDown.Items, ...
                sprintf('Voice %d', size(app.f0mat, 2))];
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            if size(app.f0mat, 2) > 1
                app.f0mat(:, app.idx_v) = [];
                app.SwitchDropDown.Items = app.SwitchDropDown.Items(1:end - 1);
                app.PartDropDown.Items = app.PartDropDown.Items(1:end - 1);
                app.idx_v = 1;
            else
                app.f0mat(:, 1) = 0;
            end
            
            h_refresh(app);
        end

        % Drop down opening function: SwitchDropDown
        function SwitchDropDownOpening(app, event)
            value = app.SwitchDropDown.Value;
            s = strsplit(value, ' ');
            app.idx_v = str2double(s{end});
            
            h_refresh(app);
        end

        % Value changed function: SwitchDropDown
        function SwitchDropDownValueChanged(app, event)
            value = app.SwitchDropDown.Value;
            s = strsplit(value, ' ');
            app.idx_v = str2double(s{end});
            
            h_refresh(app);
        end

        % Value changed function: F_maxEditField
        function F_maxEditFieldValueChanged(app, event)
            ymax = app.F_maxEditField.Value;
            
            if app.F(end) < ymax
                ymax = app.F(end);
            end

            if app.UIAxes_P.YLim(1) < ymax
                ylim(app.UIAxes_P, [app.UIAxes_P.YLim(1) ymax]);
                ylim(app.UIAxes_H, [app.UIAxes_H.YLim(1) ymax]);
            end
        end

        % Value changed function: F_minEditField
        function F_minEditFieldValueChanged(app, event)
            ymin = app.F_minEditField.Value;
            
            if ymin < app.F(1)
                ymin = app.F(1);
            end

            if ymin < app.UIAxes_P.YLim(2)
                ylim(app.UIAxes_P, [ymin app.UIAxes_P.YLim(2)]);
                ylim(app.UIAxes_H, [ymin app.UIAxes_H.YLim(2)]);
            end
        end

        % Value changed function: T_maxEditField
        function T_maxEditFieldValueChanged(app, event)
            xmax = app.T_maxEditField.Value;
            
            if app.T(end) < xmax
                xmax = app.T(end);
            end

            if app.UIAxes_P.XLim(1) < xmax
                xlim(app.UIAxes_P, [app.UIAxes_P.XLim(1) xmax]);
                xlim(app.UIAxes_H, [app.UIAxes_H.XLim(1) xmax]);
            end
        end

        % Value changed function: T_minEditField
        function T_minEditFieldValueChanged(app, event)
            xmin = app.T_minEditField.Value;
            
            if xmin < app.T(1)
                xmin = app.T(1);
            end

            if xmin < app.UIAxes_P.XLim(2)
                xlim(app.UIAxes_P, [xmin app.UIAxes_P.XLim(2)]);
                xlim(app.UIAxes_H, [xmin app.UIAxes_H.XLim(2)]);
            end
        end

        % Value changed function: ScaleDropDown
        function ScaleDropDownValueChanged(app, event)
            value = app.ScaleDropDown.Value;
            
            if strcmp('Log', value)
                app.UIAxes_H.YScale = 'log';
                app.UIAxes_P.YScale = 'log';
            elseif strcmp('Linear', value)
                app.UIAxes_H.YScale = 'linear';
                app.UIAxes_P.YScale = 'linear';
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            value_p = app.PartDropDown.Value;
            
            if strcmp(value_p, 'All')
                y = 0;
                
                for i=1:size(app.f0mat, 2)
                    y = y + helper.h_f0synth(app.f0mat(:, i), app.fs, app.dec_n);
                end
            else
                s = strsplit(value_p, ' ');
                idx = str2double(s{end});
                y = helper.h_f0synth(app.f0mat(:, idx), app.fs, app.dec_n);
            end
            
            if app.woriginalButton.Value
                y = y .* sqrt(18 * sum(app.x.^2)/sum(y.^2)) + app.x(1:length(y))';
            end
            
            y = max(abs(app.x)) .* y ./ max(abs(y));
            
            % get excerpt
            t_s = app.startEditField.Value;
            t_e = app.endEditField.Value;
            idx_s = round(app.fs*(t_s - app.T(1)));
            idx_e = round(app.fs*(t_e - app.T(1)));
            
            idx_s = max(1, idx_s);
            idx_e = min(numel(y), idx_e);

            % sound
            if idx_s < idx_e
                sound(y(idx_s:idx_e), app.fs);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            % check if the output folder exists
            if ~exist(config_f0().outputdir, 'dir')
                mkdir(config_f0().outputdir);
            end

            %
            f0mat = app.f0mat;
            fs = app.fs;
            F = app.F;
            T = app.T;
            dec_n = app.dec_n;
            P = 10.^(app.P./10);
            H = app.H;
            
            save(strcat(app.outputdir, app.song, '_f0data.mat'), 'f0mat', 'fs', 'F', 'T', 'dec_n', 'P', 'H');
            
            colname_v = arrayfun(@(x) strcat('voice_', num2str(x)), 1:size(f0mat, 2), 'UniformOutput', false);
            writetable(array2table([T' f0mat], 'VariableNames', ['time', colname_v]),...
                strcat(app.outputdir, app.song, '_f0.csv'),...
                'WriteVariableNames', true, 'WriteRowNames', false, 'Delimiter', ',');
            
            y = 0;
            for i=1:size(app.f0mat, 2)
                y_i = helper.h_f0synth(app.f0mat(:, i), app.fs, app.dec_n);
                audiowrite(strcat(app.outputdir, app.song, '_f0_p', num2str(i), '.wav'), y_i, app.fs);
                
                y = y + y_i;
            end
            
            y = 0.7 .* y ./ max(abs(y));
            audiowrite(strcat(app.outputdir, app.song, '_f0.wav'), y, app.fs);
            
            y = y .* sqrt(18 * sum(app.x.^2)/sum(y.^2)) + app.x(1:length(y))';
            y = max(abs(app.x)) .* y ./ max(abs(y));
            audiowrite(strcat(app.outputdir, app.song, '_f0_withOrig.wav'), y, app.fs);
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            h_startup(app);
            
            value = app.ScaleDropDown.Value;
            
            if strcmp('Log', value)
                app.UIAxes_H.YScale = 'log';
                app.UIAxes_P.YScale = 'log';
            elseif strcmp('Linear', value)
                app.UIAxes_H.YScale = 'linear';
                app.UIAxes_P.YScale = 'linear';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIAxes_P = uiaxes(app.UIFigure);
            title(app.UIAxes_P, 'Scalogram')
            xlabel(app.UIAxes_P, 'Time (sec)')
            ylabel(app.UIAxes_P, 'Frequency (Hz)')
            zlabel(app.UIAxes_P, 'Z')
            app.UIAxes_H = uiaxes(app.UIFigure);
            title(app.UIAxes_H, 'Harmonicity')
            xlabel(app.UIAxes_H, 'Time (sec)')
            ylabel(app.UIAxes_H, 'Frequency (Hz)')
            zlabel(app.UIAxes_H, 'Z')
            app.ModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Title = 'Mode';
            app.DrawButton = uiradiobutton(app.ModeButtonGroup);
            app.DrawButton.Text = 'Draw';
            app.DrawButton.Value = true;
            app.EraseButton = uiradiobutton(app.ModeButtonGroup);
            app.EraseButton.Text = 'Erase';
            app.AutoButton = uiradiobutton(app.ModeButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.LineButton = uiradiobutton(app.ModeButtonGroup);
            app.LineButton.Text = 'Line';
            app.WriteButton = uibutton(app.UIFigure, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Text = 'Write';
            app.NewButton = uibutton(app.UIFigure, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.SwitchDropDownLabel = uilabel(app.UIFigure);
            app.SwitchDropDownLabel.Text = 'Switch';
            app.SwitchDropDown = uidropdown(app.UIFigure);
            app.SwitchDropDown.Items = {'Voice 1'};
            app.SwitchDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SwitchDropDownOpening, true);
            app.SwitchDropDown.ValueChangedFcn = createCallbackFcn(app, @SwitchDropDownValueChanged, true);
            app.SwitchDropDown.Value = 'Voice 1';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PartDropDownLabel = uilabel(app.UIFigure);
            app.PartDropDownLabel.Text = 'Part';
            app.PartDropDown = uidropdown(app.UIFigure);
            app.PartDropDown.Items = {'All', 'Voice 1'};
            app.PartDropDown.Value = 'All';
            app.woriginalButton = uibutton(app.UIFigure, 'state');
            app.woriginalButton.Text = 'w/ original';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.T_minEditFieldLabel = uilabel(app.UIFigure);
            app.T_minEditFieldLabel.Text = 'T_min';
            app.T_minEditField = uieditfield(app.UIFigure, 'numeric');
            app.T_minEditField.ValueChangedFcn = createCallbackFcn(app, @T_minEditFieldValueChanged, true);
            app.T_maxEditFieldLabel = uilabel(app.UIFigure);
            app.T_maxEditFieldLabel.Text = 'T_max';
            app.T_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.T_maxEditField.ValueChangedFcn = createCallbackFcn(app, @T_maxEditFieldValueChanged, true);
            app.F_minEditFieldLabel = uilabel(app.UIFigure);
            app.F_minEditFieldLabel.Text = 'F_min';
            app.F_minEditField = uieditfield(app.UIFigure, 'numeric');
            app.F_minEditField.ValueChangedFcn = createCallbackFcn(app, @F_minEditFieldValueChanged, true);
            app.F_maxEditFieldLabel = uilabel(app.UIFigure);
            app.F_maxEditFieldLabel.Text = 'F_max';
            app.F_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.F_maxEditField.ValueChangedFcn = createCallbackFcn(app, @F_maxEditFieldValueChanged, true);
            app.ReadButton = uibutton(app.UIFigure, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.ScaleDropDownLabel = uilabel(app.UIFigure);
            app.ScaleDropDownLabel.Text = 'Scale';
            app.ScaleDropDown = uidropdown(app.UIFigure);
            app.ScaleDropDown.Items = {'Log', 'Linear'};
            app.ScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @ScaleDropDownValueChanged, true);
            app.ScaleDropDown.Value = 'Log';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.startEditFieldLabel = uilabel(app.UIFigure);
            app.startEditFieldLabel.Text = 'start';
            app.startEditField = uieditfield(app.UIFigure, 'numeric');
            app.endEditFieldLabel = uilabel(app.UIFigure);
            app.endEditFieldLabel.Text = 'end';
            app.endEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = f0_ui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end